classdef drum < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        ButtonCompOrtho     matlab.ui.control.Button
        ButtonCompSpectrum  matlab.ui.control.Button
        Label_4             matlab.ui.control.Label
        Label_3             matlab.ui.control.Label
        EditFieldR0         matlab.ui.control.NumericEditField
        Label_2             matlab.ui.control.Label
        ButtonCompOmega     matlab.ui.control.Button
        EditFieldCs         matlab.ui.control.NumericEditField
        EditField_3Label    matlab.ui.control.Label
        EditFieldL          matlab.ui.control.NumericEditField
        EditField_2Label    matlab.ui.control.Label
        EditFieldA          matlab.ui.control.NumericEditField
        Label               matlab.ui.control.Label
        RightPanel          matlab.ui.container.Panel
        LabelTest           matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    methods (Access = private)

        function results =  OmegaResonance(app,n,order_max,cs_ca,L_a,sigma_a_rho,gamma_p_a,rootBessel,rootBesselDiff)
            % n,order,cs/ca,L/a,sigma/a/rho,gamma*pa/a,root of bessel,root of bessel's derivative
            % load('rootBessel.mat')
            % load('rootBesselDiff.mat')
            % OmegaResonance(0,50, 0.3,1.5, 3.3 ,rootBessel,rootBesselDiff)
            % as B in the paper
            if n==0% ii从0开始编号
                %lambda
                lambda=zeros(order_max,order_max);
                for ii=1:order_max
                    for mm=1:order_max
                        lambda(ii,mm)=2*rootBessel(n+1,mm)/(rootBesselDiff(n+1,ii)^2-rootBessel(n+1,mm)^2);
                    end
                end
                Omega_loop=0:0.001:10;% as the paper
                det_loop=zeros(1,length(Omega_loop));


                for i_loop=1:length(Omega_loop)
                    Omega=Omega_loop(i_loop);
                    % A
                    A=zeros(order_max);
                    for ii=1:order_max%  ii means i,jj means j in the paper
                        for jj=1:order_max
                            A(ii,jj)=sum(lambda(ii,1:order_max).*lambda(jj,1:order_max)./ ...
                                (cs_ca^2*rootBessel(n+1,1:order_max).^2-Omega^2))/sigma_a_rho;
                        end
                    end
                    % K
                    K=zeros(order_max);

                    for ii=1:order_max
                        sqrt_tmp=sqrt(abs(rootBesselDiff(n+1,ii)^2-Omega^2));
                        if Omega<rootBesselDiff(n+1,ii)
                            K(ii,ii)=-Omega^2/sqrt_tmp*coth(L_a*sqrt_tmp);% gamma*p_a/a can be eliminate
                        elseif Omega>rootBesselDiff(n+1,ii)
                            K(ii,ii)=Omega^2/sqrt_tmp*cot(L_a*sqrt_tmp);
                        else
                            K(ii,ii)=inf;
                        end
                    end

                    mat_temp=K*A;
                    mat_temp(isnan(mat_temp))=0;
                    det_loop(i_loop)=det(diag(ones(1,order_max))+mat_temp);

                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            else%  ii从1开始编号
                % lambda
                lambda=zeros(order_max,order_max);
                for ii=1:order_max
                    for mm=1:order_max
                        lambda(ii,mm)=rootBesselDiff(n+1,ii+1)/sqrt(rootBesselDiff(n+1,ii+1)^2-n^2)*...
                            2*rootBessel(n+1,mm)/(rootBesselDiff(n+1,ii+1)^2-rootBessel(n+1,mm)^2);
                    end
                end
                Omega_loop=0:0.001:10;% as the paper
                det_loop=zeros(1,length(Omega_loop));

                for i_loop=1:length(Omega_loop)
                    Omega=Omega_loop(i_loop);
                    % A
                    A=zeros(order_max);
                    for ii=1:order_max%  ii means i,jj means j in the paper,ii无需+1
                        for jj=1:order_max
                            A(ii,jj)=sum(lambda(ii,1:order_max).*lambda(jj,1:order_max)./ ...
                                (cs_ca^2*rootBessel(n+1,1:order_max).^2-Omega^2))/sigma_a_rho;
                        end
                    end
                    % K
                    K=zeros(order_max);

                    for ii=1:order_max
                        sqrt_tmp=sqrt(abs(rootBesselDiff(n+1,ii+1)^2-Omega^2));
                        if Omega<rootBesselDiff(n+1,ii+1)
                            K(ii,ii)=-Omega^2/sqrt_tmp*coth(L_a*sqrt_tmp);% gamma*p_a/a can be eliminate
                        elseif Omega>rootBesselDiff(n+1,ii+1)
                            K(ii,ii)=Omega^2/sqrt_tmp*cot(L_a*sqrt_tmp);
                        else
                            K(ii,ii)=inf;
                        end
                    end
                    mat_temp=K*A;
                    mat_temp(isnan(mat_temp))=0;
                    det_loop(i_loop)=det(diag(ones(1,order_max))+mat_temp);

                end
            end
            figure(1);clf;hold on
            plot(Omega_loop(det_loop>=0),det_loop(det_loop>=0),'.r')
            plot(Omega_loop(det_loop<0),-det_loop(det_loop<0),'.b')
            set(gca,'yscale','log')

            [~,OmegaRe1]=findpeaks(-abs(det_loop),Omega_loop);

            tempSign=sign(det_loop(1:end-1)).*sign(det_loop(2:end));
            OmegaRe2= Omega_loop(unique([find(tempSign==-1);find(tempSign==-1)+1;find(tempSign==-1)-1]));
            OmegaRe=intersect(OmegaRe1,OmegaRe2);
            save(sprintf('OmegaRe/%d.mat',n),'OmegaRe');
            results=true;
            results=true;
        end

        function results = OmegaReLoader(app)
            fileList=dir(strcat([pwd,'/Omega/*.mat']));
            order_max=40;
            Omega=zeros(length(fileList),order_max);
            for ni =1:length(fileList)
                load(strcat(fileList(ni).folder,'/', fileList(ni).name));
                Omega(ni,:)=OmegaRe(1:order_max);
            end
            save('OmegaRe.mat',"Omega")
            results=true;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ButtonCompOmega
        function ButtonCompOmegaPushed(app, event)
            a=app.EditFieldA.Value;% 鼓的半径
            L=app.EditFieldL.Value;% 鼓的深度
            cs=app.EditFieldCs.Value;% 鼓皮中声速

            ca = 340;% 空气中声速

            gamma = 1.4; % 空气绝热系数
            rho = 1.225;% 空气密度
            d = 1e-4; % 气球厚度
            sigma = rho*d;% 气球面密度
            pa = 1e5;% 大气压强

            n=12;% 展开阶数
            order_max=40;% 取前~个根
            cs_ca=cs/ca;
            L_a=L/a;

            rootBessel=load (strcat([pwd '\rootBessel.mat']));
            rootBesselDiff=load (strcat([pwd '\rootBesselDiff.mat']));
            rootBessel=rootBessel.rootBessel;
            rootBesselDiff=rootBesselDiff.rootBesselDiff;

            sigma_a_rho=sigma/a/rho;
            gamma_p_a=gamma*pa/a;
            for ni =0:n
                app.LabelTest.Text=strcat(['计算本征频率到第' num2str(ni) '阶']);
                OmegaResonance(app,ni,order_max,cs_ca,L_a,sigma_a_rho,gamma_p_a,rootBessel,rootBesselDiff);
            end
            OmegaReLoader(app)
        end

        % Button pushed function: ButtonCompOrtho
        function ButtonCompOrthoPushed(app, event)
            a=app.EditFieldA.Value;% 鼓的半径
            L=app.EditFieldL.Value;% 鼓的深度
            cs=app.EditFieldCs.Value;% 鼓皮中声速

            ca = 340;% 空气中声速

            gamma = 1.4; % 空气绝热系数
            rho = 1.5e3;%橡胶密度
            d = 1e-4; % 气球厚度
            sigma = rho*d;% 气球面密度
            pa = 1e5;% 大气压强

            n=12;% 展开阶数

            cs_ca=cs/ca;
            L_a=L/a;

            rootBessel=load (strcat([pwd '\rootBessel.mat']));
            rootBesselDiff=load (strcat([pwd '\rootBesselDiff.mat']));
            OmegaRe=load (strcat([pwd '\OmegaRe.mat']));
            rootBessel=rootBessel.rootBessel;
            rootBesselDiff=rootBesselDiff.rootBesselDiff;
            Omega=OmegaRe.Omega;

            orderMax=size(Omega,2);
            orderN=size(Omega,1)-1;% 取前~个根

            sigma_a_rho=sigma/a/rho;
            gamma_p_a=gamma*pa/a;

            syms r z theta
            % syms r_a z_L
            r_a=r/a;
            z_L=z/L;%归一化
            rootBessel=rootBessel(1:orderN+1,1:orderMax);
            rootBesselDiff=rootBesselDiff(1:orderN+1,1:orderMax);
            Omega=Omega(1:orderN+1,1:orderMax)*ca/a;
            n = (0:orderN)';m = 1:orderMax;
            n = repmat(n,1,orderMax);
            m = repmat(m, orderN);
            phi_n_m=sqrt(2)*besselj(n, rootBessel*r_a)./besselj(n+1, rootBessel);
            % phi部分完毕

            epsilon=L/a*sqrt(abs((Omega*a/ca).^2-rootBesselDiff.^2));
            zeta=(Omega*a/ca<rootBesselDiff).*(cosh(epsilon-epsilon*z_L)./epsilon)+...
                (Omega*a/ca==rootBesselDiff)+...
                (Omega*a/ca>rootBesselDiff).*(cos(epsilon*z_L)+tan(epsilon).*sin(epsilon*z_L));
            % zeta部分完毕
            w=phi_n_m.*zeta.*cos(n*theta);
            % 得到正交基
            % 先行假设这些正交基，归一，r在=0~1，z在=0~1
            heft=eval(int(int(int(w.^2*r*pa,r,[0,a]),z,[0,L]),theta ,[0,2*pi]));
            w=w./heft;
            save('w.mat','w')
        end

        % Button pushed function: ButtonCompSpectrum
        function ButtonCompSpectrumPushed(app, event)
            a=app.EditFieldA.Value;% 鼓的半径
            L=app.EditFieldL.Value;% 鼓的深度
            cs=app.EditFieldCs.Value;% 鼓皮中声速
            ca=340;
            syms r z theta
            w=load (strcat([pwd '\w.mat']));
            OmegaRe=load (strcat([pwd '\OmegaRe.mat']));
            w=w.w;
            Omega=OmegaRe.Omega;

            r0=app.EditFieldR0.Value;

            d1=sqrt(r0^2+r^2+2*r*r0*cos(theta));
            d2=a-r;
            p_n_m_k=int(int(int(w*d1/(d1+d2),r,[0,a]),z,[0,L]),theta ,[0,2*pi]) ;% 获得诸频率分量，对其
            % 发声，未测试
            t=0:0.001:1;
            sound(sin(2*pi*Omega*a/ca.*t));
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '鼓的半径';
            app.EditFieldA = uieditfield(app.LeftPanel, 'numeric');
            app.EditFieldA.Value = 0.2;
            app.EditField_2Label = uilabel(app.LeftPanel);
            app.EditField_2Label.Text = '鼓的深度';
            app.EditFieldL = uieditfield(app.LeftPanel, 'numeric');
            app.EditFieldL.Value = 0.3;
            app.EditField_3Label = uilabel(app.LeftPanel);
            app.EditField_3Label.Text = '鼓皮中波速';
            app.EditFieldCs = uieditfield(app.LeftPanel, 'numeric');
            app.EditFieldCs.Value = 10;
            app.ButtonCompOmega = uibutton(app.LeftPanel, 'push');
            app.ButtonCompOmega.ButtonPushedFcn = createCallbackFcn(app, @ButtonCompOmegaPushed, true);
            app.ButtonCompOmega.Text = '1.开始计算本征频率';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = {'请输入鼓的相关参数'; '均为国际单位'};
            app.EditFieldR0 = uieditfield(app.LeftPanel, 'numeric');
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = '请输入敲击位置';
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Text = '敲击点离鼓心距离';
            app.ButtonCompSpectrum = uibutton(app.LeftPanel, 'push');
            app.ButtonCompSpectrum.ButtonPushedFcn = createCallbackFcn(app, @ButtonCompSpectrumPushed, true);
            app.ButtonCompSpectrum.Text = '3.计算发声频谱';
            app.ButtonCompOrtho = uibutton(app.LeftPanel, 'push');
            app.ButtonCompOrtho.ButtonPushedFcn = createCallbackFcn(app, @ButtonCompOrthoPushed, true);
            app.ButtonCompOrtho.Text = '2.计算正交基';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LabelTest = uilabel(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = drum

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end