classdef page3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Label_10          matlab.ui.control.Label
        Button_9          matlab.ui.control.Button
        Button_8          matlab.ui.control.Button
        Button_7          matlab.ui.control.Button
        Button_6          matlab.ui.control.Button
        Button_5          matlab.ui.control.Button
        Button_4          matlab.ui.control.Button
        Slider            matlab.ui.control.Slider
        EditField_cs      matlab.ui.control.NumericEditField
        EditField_9Label  matlab.ui.control.Label
        msLabel_2         matlab.ui.control.Label
        EditField_n       matlab.ui.control.NumericEditField
        Label_9           matlab.ui.control.Label
        EditField_sigma   matlab.ui.control.NumericEditField
        Label_8           matlab.ui.control.Label
        EditField_a       matlab.ui.control.NumericEditField
        Label_6           matlab.ui.control.Label
        EditField_a0      matlab.ui.control.NumericEditField
        Label_5           matlab.ui.control.Label
        EditField_rho     matlab.ui.control.NumericEditField
        Label_4           matlab.ui.control.Label
        EditField_T       matlab.ui.control.NumericEditField
        Label_3           matlab.ui.control.Label
        EditField_L       matlab.ui.control.NumericEditField
        Label_2           matlab.ui.control.Label
        amLabel           matlab.ui.control.Label
        kgmLabel_2        matlab.ui.control.Label
        NmLabel           matlab.ui.control.Label
        kgmLabel          matlab.ui.control.Label
        mLabel_2          matlab.ui.control.Label
        mLabel            matlab.ui.control.Label
        Button_3          matlab.ui.control.Button
        UIAxes2           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ButtonPushed(app, event)
            delete(app.UIFigure)
            page2
        end

        % Button pushed function: Button_3
        function Button_2Pushed(app, event)

            h=waitbar(0,'请稍等，计算耦合体系本征频率中');
            L=app.EditField_L.Value;
            a=app.EditField_a.Value;
            cs=app.EditField_T.Value;
            rho=app.EditField_rho.Value;
            a0=app.EditField_a0.Value;
            order_max=app.EditField_n.Value;
            sigma =app.EditField_sigma.Value;% 气球面密度
            ca = 340;% 空气中声速
            cs_ca=cs/ca;L_a=L/a;sigma_a_rho=sigma/a/rho;
            load('rootBessel.mat')
            load('rootBesselDiff.mat')
            for n=0:order_max
                waitbar(n/order_max,h)
                OmegaResonance(n,order_max, cs_ca,L_a, sigma_a_rho,rootBessel,rootBesselDiff);
            end
            delete(h);
            save('para.mat','L_a','sigma_a_rho','cs_ca','a0','L','a','ca','order_max','sigma','cs','rho')

            fileList=dir('./OmegaRe/*.mat');

            Omega=zeros(length(fileList),order_max);
            for ni =1:length(fileList)
                load(strcat(fileList(ni).folder,'/', fileList(ni).name));
                while length(OmegaRe)<order_max
                    OmegaRe=[OmegaRe nan];
                end
                Omega(ni,:)=OmegaRe(1:order_max);
            end
            save('OmegaRe.mat',"Omega")

            fileList=dir('./Trans/*.mat');

            transMat=zeros(length(fileList),order_max,order_max);
            for ni =1:length(fileList)
                load(strcat(fileList(ni).folder,'/', fileList(ni).name));
                transMat(ni,:,:)=trans(1:order_max,1:order_max);
            end
            save('transMat.mat',"transMat")
            %%
            load(".\OmegaRe.mat"); % 计算得到的OmegaRe
            load('.\transMat.mat'); % a^k_ni
            load('.\rootBessel.mat');
            load('.\rootBesselDiff.mat');
            load('.\para.mat')
            Omega=Omega(1:order_max+1,1:order_max)/2;% 一致化
            save('Omega.mat','Omega');
            r_num=100;
            theta_num=100;% 切分的数目
            z_num=100;
            r=linspace(0,1,r_num)';% 注意有转置，列矢*行矢比较方便 r(0)对应中间
            theta=linspace(0,2*pi,theta_num);
            z(1,1,:)=linspace(0,L,z_num);
            % z0=zeros(r_num,theta_num);
            % z0(floor(r_num*a0/a)+1,1)=1; % 敲击位置，方程可以后续再改
            save('cut_num.mat','r_num','theta_num','z_num');
            %%
            % 锥形初始条件
            h0=0.001;

            ab=sqrt(r.^2+a0^2-2*r*a0*cos(theta));
            cosoba=(r.^2+ab.^2-a0^2)/2./r./ab;
            coscbo=-cosoba;
            cb=(2*coscbo.*r+sqrt((2*coscbo.*r).^2-4*(r.^2-1^2)))/2;
            z0=h0*cb./(cb+ab);
            z0(isnan(z0))=h0*1/(1+a0);
            % surfShape(z0,r,theta);
            %%
            ds=pi*2*r/r_num/theta_num;
            ds=repmat(ds,1,theta_num);
            w_cm=zeros(order_max+1,order_max);
            shape_c=cell(order_max+1,order_max);
            for nn=0:order_max
                for mm=1:order_max
                    if nn==0 && mm==1
                        z1=sqrt(2)*ones(size(r))*cos(nn*theta)/sqrt(pi)/sqrt(2);
                    elseif nn==0
                        z1=sqrt(2)*rootBesselDiff(nn+1,mm)*besselj(nn,rootBesselDiff(nn+1,mm)*r)/...
                            besselj(nn,rootBesselDiff(nn+1,mm))/sqrt(rootBesselDiff(nn+1,mm)^2-nn^2)*...
                            cos(nn*theta)/sqrt(pi)/sqrt(2);
                    else%mm 需要+1
                        z1=sqrt(2)*rootBesselDiff(nn+1,mm+1)*besselj(nn,rootBesselDiff(nn+1,mm+1)*r)/...
                            besselj(nn,rootBesselDiff(nn+1,mm+1))/sqrt(rootBesselDiff(nn+1,mm+1)^2-nn^2)*...
                            cos(nn*theta)/sqrt(pi);
                    end
                    shape_c{nn+1,mm}=z1;
                    w_cm(nn+1,mm)=sum(sum(z0.*z1.*ds));
                end
            end

            shape_m=cell(order_max,order_max);
            for nn=0:order_max
                for mm=1:order_max
                    z1=sqrt(2)*besselj(nn,rootBessel(nn+1,mm)*r)/...
                        besselj(nn+1,rootBessel(nn+1,mm))*...
                        cos(nn*theta)/sqrt(pi)/sqrt(1+(nn==0));
                    shape_m{nn+1,mm}=z1;
                end
            end

            %膜的初态按照腔的本征态展开
            %%
            % 角标应为n m k
            w_cn=zeros(size(w_cm));
            for nn=0:order_max
                w_cn(nn+1,:)=w_cm(nn+1,1:order_max)*reshape(transMat(nn+1,1:order_max,1:order_max),order_max,order_max);
            end
            save('wcn.mat',"w_cn")
            save('shape.mat','shape_m','shape_c','r','theta')
            delete(app.UIFigure)
            page4
        end

        % Callback function: EditField_L, EditField_a, EditField_a0,
        % UIAxes2
        function Value_Changed(app, event)
            cla(app.UIAxes2);
            L=app.EditField_L.Value;
            a=app.EditField_a.Value;
            rho=app.EditField_rho.Value;
            a0=app.EditField_a0.Value;
            hh=0:0.001:L;
            th=0:pi/20:2*pi;
            xx=zeros(length(hh),length(th));
            yy=zeros(length(hh),length(th));
            zz=zeros(length(hh),length(th));
            for hi =1:length(hh)
                xx(hi,:)=a.*cos(th);
                zz(hi,:)=hh(hi);%%%%%%
                yy(hi,:)=a.*sin(th);
            end
            drum_draw=surf(app.UIAxes2,xx,yy,zz);
            app.UIAxes2.NextPlot='add';
            set(drum_draw,'edgecolor','none');

            axis(app.UIAxes2,[-a*1.1,a*1.1,-a*1.1,a*1.1,-L*1.1,L*1.1]);
            xx=a.*cos(th);
            yy=a.*sin(th);
            zz=zeros(size(xx))+L;
            fill3(app.UIAxes2,xx,yy,zz,[1,0.95*(1-app.EditField_cs.Value/400),0.8*(1-app.EditField_cs.Value/200)]);% 后面的是颜色
            app.UIAxes2.NextPlot='add';
            plot3(app.UIAxes2,a0*a,0,0,'.','color','r','MarkerSize',10);
            axis(app.UIAxes2,'equal');
        end

        % Value changed function: EditField_sigma
        function EditField_7ValueChanged(app, event)
            sigma=app.EditField_sigma.Value;
            T=app.EditField_T.Value;
            cs=sqrt(T/sigma);
            app.EditField_cs.Value=cs;
        end

        % Value changed function: EditField_T
        function EditField_TValueChanged(app, event)
            T_input=app.EditField_T.Value;
            app.Slider.Value=T_input;
            cs=sqrt(T_input/app.EditField_sigma.Value);
            app.EditField_cs.Value=cs;
            cla(app.UIAxes2);
            L=app.EditField_L.Value;
            a=app.EditField_a.Value;
            rho=app.EditField_rho.Value;
            a0=app.EditField_a0.Value;
            hh=0:0.001:L;
            th=0:pi/200:2*pi;
            xx=zeros(length(hh),length(th));
            yy=zeros(length(hh),length(th));
            zz=zeros(length(hh),length(th));
            for hi =1:length(hh)
                xx(hi,:)=a.*cos(th);
                zz(hi,:)=hh(hi);%%%%%%
                yy(hi,:)=a.*sin(th);
            end
            drum_draw=surf(app.UIAxes2,xx,yy,zz);
            app.UIAxes2.NextPlot='add';
            set(drum_draw,'edgecolor','none');
            view(app.UIAxes2,[-1,-1,1]);
            axis(app.UIAxes2,[-a*1.1,a*1.1,-a*1.1,a*1.1,-L*1.1,L*1.1]);
            xx=a.*cos(th);
            yy=a.*sin(th);
            zz=zeros(size(xx))+L;
            fill3(app.UIAxes2,xx,yy,zz,[1,0.95*(1-app.EditField_cs.Value/400),0.8*(1-app.EditField_cs.Value/200)]);
            app.UIAxes2.NextPlot='add';
            plot3(app.UIAxes2,a0*a,0,0,'.','color','r','MarkerSize',10);
            axis(app.UIAxes2,'equal');
        end

        % Callback function: Slider, Slider
        function SliderValueChanged(app, event)
            T_slider=app.Slider.Value;
            app.EditField_T.Value=T_slider;
            cs=sqrt(T_slider/app.EditField_sigma.Value);
            app.EditField_cs.Value=cs;
            cla(app.UIAxes2);
            L=app.EditField_L.Value;
            a=app.EditField_a.Value;
            rho=app.EditField_rho.Value;
            a0=app.EditField_a0.Value;
            hh=0:0.001:L;
            th=0:pi/20:2*pi;
            xx=zeros(length(hh),length(th));
            yy=zeros(length(hh),length(th));
            zz=zeros(length(hh),length(th));
            for hi =1:length(hh)
                xx(hi,:)=a.*cos(th);
                zz(hi,:)=hh(hi);%%%%%%
                yy(hi,:)=a.*sin(th);
            end
            drum_draw=surf(app.UIAxes2,xx,yy,zz);
            app.UIAxes2.NextPlot='add';
            set(drum_draw,'edgecolor','none');
            view(app.UIAxes2,[-1,-1,1]);
            axis(app.UIAxes2,[-a*1.1,a*1.1,-a*1.1,a*1.1,-L*1.1,L*1.1]);
            xx=a.*cos(th);
            yy=a.*sin(th);
            zz=zeros(size(xx))+L;
            fill3(app.UIAxes2,xx,yy,zz,[1,0.95*(1-app.EditField_cs.Value/400),0.8*(1-app.EditField_cs.Value/200)]);
            app.UIAxes2.NextPlot='add';
            plot3(app.UIAxes2,a0*a,0,0,'.','color','r','MarkerSize',10);
            axis(app.UIAxes2,'equal');
        end

        % Value changed function: EditField_cs
        function EditField_csValueChanged(app, event)
            cs=app.EditField_cs.Value;
            T=cs*cs*app.EditField_sigma.Value;
            app.Slider.Value=T;
            app.EditField_T.Value=T;
            cla(app.UIAxes2);
            L=app.EditField_L.Value;
            a=app.EditField_a.Value;
            rho=app.EditField_rho.Value;
            a0=app.EditField_a0.Value;
            hh=0:0.001:L;
            th=0:pi/20:2*pi;
            xx=zeros(length(hh),length(th));
            yy=zeros(length(hh),length(th));
            zz=zeros(length(hh),length(th));
            for hi =1:length(hh)
                xx(hi,:)=a.*cos(th);
                zz(hi,:)=hh(hi);%%%%%%
                yy(hi,:)=a.*sin(th);
            end
            drum_draw=surf(app.UIAxes2,xx,yy,zz);
            app.UIAxes2.NextPlot='add';
            set(drum_draw,'edgecolor','none');
            view(app.UIAxes2,[-1,-1,1]);
            axis(app.UIAxes2,[-a*1.1,a*1.1,-a*1.1,a*1.1,-L*1.1,L*1.1]);
            xx=a.*cos(th);
            yy=a.*sin(th);
            zz=zeros(size(xx))+L;
            fill3(app.UIAxes2,xx,yy,zz,[1,0.95*(1-app.EditField_cs.Value/300),0.8*(1-app.EditField_cs.Value/150)]);
            app.UIAxes2.NextPlot='add';
            plot3(app.UIAxes2,a0*a,0,0,'.','color','r','MarkerSize',10);
            axis(app.UIAxes2,'equal');
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            page4_0
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            page4
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            page_5_0
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            page5_1
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [0 1 2];
            app.UIAxes2.YTick = [0 1 2];
            app.UIAxes2.ZTick = [0 1 2];
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @Value_Changed, true);
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_3.Text = '开始计算';
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Text = 'm';
            app.mLabel_2 = uilabel(app.UIFigure);
            app.mLabel_2.Text = 'm';
            app.kgmLabel = uilabel(app.UIFigure);
            app.kgmLabel.Text = 'kg/m²';
            app.NmLabel = uilabel(app.UIFigure);
            app.NmLabel.Text = 'N/m';
            app.kgmLabel_2 = uilabel(app.UIFigure);
            app.kgmLabel_2.Text = 'kg/m³';
            app.amLabel = uilabel(app.UIFigure);
            app.amLabel.Text = '*a m';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '鼓的高度';
            app.EditField_L = uieditfield(app.UIFigure, 'numeric');
            app.EditField_L.ValueChangedFcn = createCallbackFcn(app, @Value_Changed, true);
            app.EditField_L.Value = 0.1;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '膜上张力';
            app.EditField_T = uieditfield(app.UIFigure, 'numeric');
            app.EditField_T.ValueChangedFcn = createCallbackFcn(app, @EditField_TValueChanged, true);
            app.EditField_T.Value = 90;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '空气密度';
            app.EditField_rho = uieditfield(app.UIFigure, 'numeric');
            app.EditField_rho.Value = 1.184;
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '敲击位置';
            app.EditField_a0 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_a0.ValueChangedFcn = createCallbackFcn(app, @Value_Changed, true);
            app.EditField_a0.Value = 0.1;
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '鼓的半径';
            app.EditField_a = uieditfield(app.UIFigure, 'numeric');
            app.EditField_a.ValueChangedFcn = createCallbackFcn(app, @Value_Changed, true);
            app.EditField_a.Value = 0.05;
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '膜面密度';
            app.EditField_sigma = uieditfield(app.UIFigure, 'numeric');
            app.EditField_sigma.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            app.EditField_sigma.Value = 0.1;
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '计算阶数';
            app.EditField_n = uieditfield(app.UIFigure, 'numeric');
            app.EditField_n.Value = 10;
            app.msLabel_2 = uilabel(app.UIFigure);
            app.msLabel_2.Text = 'm/s';
            app.EditField_9Label = uilabel(app.UIFigure);
            app.EditField_9Label.Text = '膜上波速';
            app.EditField_cs = uieditfield(app.UIFigure, 'numeric');
            app.EditField_cs.ValueChangedFcn = createCallbackFcn(app, @EditField_csValueChanged, true);
            app.EditField_cs.Value = 30;
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1000];
            app.Slider.MajorTicks = [0 250 500 750 1000];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 90;
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '不同模式下的振动';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '给定初始条件的振动';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '膜上某点的振动';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.Text = ' 参数设置';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.Text = '数据导出';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '腔内某点的振动';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '(建议不超过10阶)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = page3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end