classdef page4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image                   matlab.ui.control.Image
        a0aLabel_2              matlab.ui.control.Label
        Slider_6                matlab.ui.control.Slider
        Label_5                 matlab.ui.control.Label
        zLLabel                 matlab.ui.control.Label
        EditField_theta_change  matlab.ui.control.NumericEditField
        Label_4                 matlab.ui.control.Label
        Slider_5                matlab.ui.control.Slider
        ButtonGroup             matlab.ui.container.ButtonGroup
        Button_z                matlab.ui.control.RadioButton
        Button_xy               matlab.ui.control.RadioButton
        Button_8                matlab.ui.control.Button
        EditField_z0            matlab.ui.control.NumericEditField
        Slider_3                matlab.ui.control.Slider
        EditField_a0            matlab.ui.control.NumericEditField
        a0aLabel                matlab.ui.control.Label
        Button_6                matlab.ui.control.Button
        Button_5                matlab.ui.control.Button
        Button_4                matlab.ui.control.Button
        Button_3                matlab.ui.control.Button
        Button_2                matlab.ui.control.Button
        Button                  matlab.ui.control.Button
        Slider_2                matlab.ui.control.Slider
        Label_2                 matlab.ui.control.Label
        Slider                  matlab.ui.control.Slider
        Label                   matlab.ui.control.Label
        UIAxes2_3               matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            EditField_a0ValueChanged(app)

        end

        % Callback function
        function ButtonPushed(app, event)
            delete(app.UIFigure)
            page3
        end

        % Callback function
        function Button_3Pushed(app, event)


        end

        % Callback function
        function Button_2Pushed(app, event)
            delete(app.UIFigure)
            page5
        end

        % Callback function
        function Button_4Pushed(app, event)

        end

        % Button pushed function: Button_2
        function Button_5Pushed(app, event)


        end

        % Callback function
        function Button_6Pushed(app, event)

        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            see_where=app.Slider.Value;
            save('s_w_page4.mat','see_where');
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            see_high=app.Slider_2.Value;
            save('s_h_page4.mat','see_high');
        end

        % Button pushed function: Button_4
        function Button_4Pushed2(app, event)
            page3
            delete(app.UIFigure)
        end

        % Button pushed function: Button
        function ButtonPushed2(app, event)
            page4_0
            delete(app.UIFigure)
        end

        % Button pushed function: Button_3
        function Button_3Pushed2(app, event)
            page5_0
            delete(app.UIFigure)
        end

        % Button pushed function: Button_6
        function Button_6Pushed2(app, event)
            page5_1
            delete(app.UIFigure)
        end

        % Button pushed function: Button_5
        function Button_5Pushed2(app, event)
            page6
            delete(app.UIFigure)
        end

        % Value changed function: EditField_a0
        function EditField_a0ValueChanged(app, event)
            a0= app.EditField_a0.Value;
            save('a0.mat','a0');
            app.Slider_3.Value=a0;

            load('para.mat');
            load("OmegaRe.mat"); % 计算得到的OmegaRe
            load('transMat.mat'); % a^k_ni
            load('rootBessel.mat');
            load('rootBesselDiff.mat');
            load('cut_num.mat');
            load('shape.mat');
            load("wcn.mat");

            %%
            % 锥形初始条件
            h0=0.003;

            ab=sqrt(r.^2+a0^2-2*r*a0*cos(theta));
            cosoba=(r.^2+ab.^2-a0^2)/2./r./ab;
            coscbo=-cosoba;
            cb=(2*coscbo.*r+sqrt((2*coscbo.*r).^2-4*(r.^2-1^2)))/2;
            z0=h0*cb./(cb+ab);
            z0(isnan(z0))=h0*1/(1+a0);
            z0=max(max(z0))-z0;
            z0=exp(-z0.^2);
            z0=z0-min(min(z0));

            %             for nn=0:order_max
            %                 mat_tmp=reshape(transMat(nn+1,1:order_max,1:order_max),order_max,order_max);
            %                 for ii=1:order_max
            %                     [~,idx]=max(abs(mat_tmp(:,ii)));
            %                     mat_tmp(:,ii)=-mat_tmp(:,ii)*sign(mat_tmp(idx,ii));
            %                     % 最大的数字的符号为负
            %                 end
            %                 [i, j] = linear_sum_assignment(mat_tmp');% 匈牙利算法
            %                 % 行i和列j对应，由于多了一个转置，实际上是列i和行j对应
            %                 mat_tmp=-mat_tmp;
            %                 transMat(nn+1,1:order_max,1:order_max)=mat_tmp;
            %             end

            % surfShape(z0,r,theta);
            %%
            ds=pi*2*r/r_num/theta_num;
            ds=repmat(ds,1,theta_num);
            w_cm=zeros(order_max+1,order_max);
            shape_c=cell(order_max+1,order_max);
            for nn=0:order_max
                for mm=1:order_max
                    if nn==0 && mm==1
                        z1=sqrt(2)*ones(size(r))*cos(nn*theta)/sqrt(pi)/sqrt(2);
                    elseif nn==0
                        z1=sqrt(2)*rootBesselDiff(nn+1,mm)*besselj(nn,rootBesselDiff(nn+1,mm)*r)/...
                            besselj(nn,rootBesselDiff(nn+1,mm))/sqrt(rootBesselDiff(nn+1,mm)^2-nn^2)*...
                            cos(nn*theta)/sqrt(pi)/sqrt(2);
                    else%mm 需要+1
                        z1=sqrt(2)*rootBesselDiff(nn+1,mm+1)*besselj(nn,rootBesselDiff(nn+1,mm+1)*r)/...
                            besselj(nn,rootBesselDiff(nn+1,mm+1))/sqrt(rootBesselDiff(nn+1,mm+1)^2-nn^2)*...
                            cos(nn*theta)/sqrt(pi);
                    end
                    shape_c{nn+1,mm}=z1;
                    w_cm(nn+1,mm)=sum(sum(z0.*z1.*ds));
                end
            end

            shape_m=cell(order_max,order_max);
            for nn=0:order_max
                for mm=1:order_max
                    z1=sqrt(2)*besselj(nn,rootBessel(nn+1,mm)*r)/...
                        besselj(nn+1,rootBessel(nn+1,mm))*...
                        cos(nn*theta)/sqrt(pi)/sqrt(1+(nn==0));
                    shape_m{nn+1,mm}=z1;
                end
            end

            %膜的初态按照腔的本征态展开
            %%
            % 角标应为n m k
            w_cn=zeros(size(w_cm));
            for nn=0:order_max
                w_cn(nn+1,:)=w_cm(nn+1,1:order_max)*reshape(transMat(nn+1,1:order_max,1:order_max),order_max,order_max);
            end
            save('wcn.mat',"w_cn")
            save('shape.mat','shape_m','shape_c','r','theta');

            hh=0:0.001:L;
            th=0:pi/200:2*pi;
            xx=zeros(length(hh),length(th));
            yy=zeros(length(hh),length(th));
            zz=zeros(length(hh),length(th));
            for hi =1:length(hh)
                xx(hi,:)=a.*cos(th);
                yy(hi,:)=a.*sin(th);
                zz(hi,:)=hh(hi);
            end
            drum_draw=surf(app.UIAxes2_3,xx,yy,zz);
            set(drum_draw,'FaceColor',[0.3 0.3 0.3], 'FaceAlpha',0.7,'edgecolor','none');
            light(app.UIAxes2_3,'Position',[-3 0 2],'Style','local');
            %              light(app.UIAxes2_2,'Position',[-3 0 2],'Style','local');
            app.UIAxes2_3.NextPlot='add';
            %             app.UIAxes2_2.NextPlot='add';

            %             axis(app.UIAxes2_2,[-a*1.2,a*1.2,-a*1.2,a*1.2,0,L*1.2]);
            xx=a.*cos(th);
            yy=a.*sin(th);
            zz=zeros(size(xx))+L;%%%%

            %             mem_draw=fill3(app.UIAxes2_2,xx,yy,zz,[1,0.95,0.8]);
            %             mem_draw.FaceAlpha=0.7;
            mem_draw=fill3(app.UIAxes2_3,xx,yy,zz,[1,0.95,0.8]);
            mem_draw.FaceAlpha=0.7;
            % 画左面的鼓的示意图
            %             app.UIAxes2_2.NextPlot='add';
            %             plot3(app.UIAxes2_2,linspace(0,1.1*a,2),zeros(1,2),zeros(1,2)+L,'-','Color','y','LineWidth',3);
            %             plot3(app.UIAxes2_2,linspace(0,0.9*a,2),linspace(0,0.9*a,2),zeros(1,2)+L,'-','Color','y','LineWidth',3);
            %             plot3(app.UIAxes2_2,0.2*a*sin(linspace(pi/4,pi/2,30)),0.2*a*cos(linspace(pi/4,pi/2,30)),zeros(1,30)+L,'-','Color','y','LineWidth',3);
            %             plot3(app.UIAxes2_2,zeros(1,2),zeros(1,2),linspace(-1.*L,-1.1*L,2)+L,'-','Color','k','LineWidth',3);
            %             plot3(app.UIAxes2_2,1.1*a,0,L,'>','Color','y','LineWidth',3);
            %             plot3(app.UIAxes2_2,linspace(0.85*a,0.9*a,2),linspace(0.8*a,0.9*a,2),zeros(1,2)+L,'-','Color','y','LineWidth',3);
            %             plot3(app.UIAxes2_2,linspace(0.8*a,0.9*a,2),linspace(0.85*a,0.9*a,2),zeros(1,2)+L,'-','Color','y','LineWidth',3);
            % %             plot3(app.UIAxes2_2,0,0,-0.1*L,'v','Color','y','LineWidth',3);
            %             plot3(app.UIAxes2_2,0,0,L,'.','Color','k','MarkerSize',10);
            %             plot3(app.UIAxes2_2,a0*a,0,L,'.','Color','r','MarkerSize',15);
            %             app.UIAxes2_2.NextPlot='replacechildren';
            %             set(app.UIAxes2_2.XAxis,'visible','off');
            %             set(app.UIAxes2_2.YAxis,'visible','off');
            %             set(app.UIAxes2_2.ZAxis,'visible','off');
            %             view(app.UIAxes2_2,[0,-1,50])
            %             light(app.UIAxes2_2,'Position',[-3 0 2],'Style','local');
            %             light(app.UIAxes2_2,'Position',[-3 0 2],'Style','local');
            %             light(app.UIAxes2_2,'Position',[-3 0 2],'Style','local');

            app.UIAxes2_3.NextPlot='add';
            plot3(app.UIAxes2_3,linspace(0,1.1*a,2),zeros(1,2),zeros(1,2)+L,'-','Color','y','LineWidth',3);
            plot3(app.UIAxes2_3,linspace(0,0.9*a,2),linspace(0,0.9*a,2),zeros(1,2)+L,'-','Color','y','LineWidth',3);
            plot3(app.UIAxes2_3,0.2*a*sin(linspace(pi/4,pi/2,30)),0.2*a*cos(linspace(pi/4,pi/2,30)),zeros(1,30)+L,'-','Color','y','LineWidth',3);
            plot3(app.UIAxes2_3,zeros(1,2),zeros(1,2),linspace(0,-1.1*L,2)+L,'-','Color','y','LineWidth',3);
            plot3(app.UIAxes2_3,1.1*a,0,L,'>','Color','y','LineWidth',3);
            plot3(app.UIAxes2_3,linspace(0.85*a,0.9*a,2),linspace(0.8*a,0.9*a,2),zeros(1,2)+L,'-','Color','y','LineWidth',3);
            plot3(app.UIAxes2_3,linspace(0.8*a,0.9*a,2),linspace(0.85*a,0.9*a,2),zeros(1,2)+L,'-','Color','y','LineWidth',3);
            plot3(app.UIAxes2_3,0,0,-0.1*L,'v','Color','y','LineWidth',3);
            plot3(app.UIAxes2_3,0,0,L,'.','Color','k','MarkerSize',10);
            plot3(app.UIAxes2_3,a0*a,0,L,'.','Color','r','MarkerSize',15);
            app.UIAxes2_3.NextPlot='replacechildren';
            set(app.UIAxes2_3.XAxis,'visible','off');
            set(app.UIAxes2_3.YAxis,'visible','off');
            set(app.UIAxes2_3.ZAxis,'visible','off');
            view(app.UIAxes2_3,[0,-1,1])
            light(app.UIAxes2_3,'Position',[-3 0 2],'Style','local');
            light(app.UIAxes2_3,'Position',[-3 0 2],'Style','local');
            light(app.UIAxes2_3,'Position',[-3 0 2],'Style','local');


            load('.\para.mat');
            load('.\wcn.mat');
            load('.\Omega.mat');
            load('.\cut_num.mat');
            load('.\shape.mat');
            fn=10000; % 横轴划分的个数
            omega_limit=10;ca=340;
            x=linspace(0,omega_limit*ca/a/2/pi,fn);
            y=zeros(1,fn);
            y_max=zeros(1,5);
            o_max=zeros(2,5);
            for nn=0:order_max
                for mm=1:order_max
                    y=y+abs(w_cn(nn+1,mm)*Omega(nn+1,mm))./(1+(x-Omega(nn+1,mm)*ca/a/2/pi).^2/10);
                end
            end
            y=y/(max(y));
            save('ft_x.mat','x');
            save('ft_y.mat','y');

            %%
            k=1;
            t=0;
            z0=app.EditField_z0.Value;
            theta0=app.EditField_theta_change.Value;

            z_num=100;
            z=linspace(0,L,z_num);
            zeta=cell(order_max+1,order_max);
            for nn=0:order_max
                for mm=1:order_max
                    if nn==0
                        epsl=L/a*sqrt(Omega(nn+1,mm).^2-rootBesselDiff(nn+1,mm).^2);
                    else
                        epsl=L/a*sqrt(Omega(nn+1,mm).^2-rootBesselDiff(nn+1,mm+1).^2);
                    end

                    if Omega(nn+1,mm)<rootBesselDiff(nn+1,mm+(nn~=1))
                        zeta{nn+1,mm}=cosh(epsl-epsl*z)/cosh(epsl);
                    elseif Omega(nn+1,mm)==rootBesselDiff(nn+1,mm+(nn~=1))
                        zeta{nn+1,mm}=ones(size(z));
                    else
                        zeta{nn+1,mm}=cos(epsl*z)+tan(epsl)*sin(epsl*z);
                    end
                end
            end

            z_id=ceil(z0*z_num+eps);
            shape_mmm=zeros(r_num,theta_num);
            shape_ccc=zeros(r_num,theta_num);
            shape_1=zeros(r_num,theta_num);
            shape_2=zeros(r_num,z_num);% 定义变量尽量在循环外面

            r_tmp=repmat(r,1,z_num);
            z_tmp=repmat(z,r_num,1);
            for t=0:0.00002:0.01
                load('s_w_page4.mat');
                load('s_h_page4.mat');

                for nn=0:order_max
                    for mm=1:order_max
                        shape_mmm=shape_mmm+shape_m{nn+1,mm}*w_cn(nn+1,mm)*cos(Omega(nn+1,mm)*ca/a*t);
                        shape_ccc=shape_ccc+shape_c{nn+1,mm}*w_cn(nn+1,mm)*cos(Omega(nn+1,mm)*ca/a*t)*sin((k-1/2)*pi*z0);
                    end
                end

                for nn=0:order_max
                    for mm=1:order_max
                        zeta_tmp=zeta{nn+1,mm};
                        shape_1=shape_1+shape_c{nn+1,mm}*w_cn(nn+1,mm)*cos(Omega(nn+1,mm)*ca/a*t)*zeta_tmp(z_id);
                    end
                end
                shape_1=shape_1/3;

                for nn=0:order_max
                    for mm=1:order_max
                        shape_tmp=shape_c{nn+1,mm};
                        shape_2=shape_2+shape_tmp(:,ceil(theta0/180/(pi/theta_num)+eps))*w_cn(nn+1,mm)*cos(Omega(nn+1,mm)*ca/a*t)*zeta{nn+1,mm};
                    end
                end
                warning('off')

                .if app.Button_xy.Value==true
                s1=surfShape(app.UIAxes2,shape_mmm*10,r,theta);
                s1.EdgeColor="none";
                app.UIAxes2.NextPlot='add';
                app.Slider_5.Visible='off';
                app.EditField_theta_change.Visible='off';
                app.Label_4.Visible='off';
                app.Slider_6.Visible='on';
                app.EditField_z0.Visible='on';
                app.zLLabel.Visible='on';
                f1=surfShape(app.UIAxes2,shape_1*30-0.01-0.003*z0,r,theta);
                f1.EdgeColor="none";
                view(app.UIAxes2,see_where,see_high); % 设置视点位置
                %axis(app.UIAxes2,'off')
                axis(app.UIAxes2,[-1,1,-1,1,-0.03,0.02])
                drawnow
                app.UIAxes2.NextPlot='replacechildren';
            else
                app.Slider_5.Visible='on';
                app.EditField_theta_change.Visible='on';
                app.Label_4.Visible='on';
                app.Slider_6.Visible='off';
                app.EditField_z0.Visible='off';
                app.zLLabel.Visible='off';
                %f2=surf(app.UIAxes2,r-a*10,z,shape_2*0.5-0.15);
                %f2=surf(app.UIAxes2,z_tmp,r_tmp,shape_2); %平移到了中间% 我看不懂魔法数字
                C=shape_2.*z_tmp;
                f2=surf(app.UIAxes2,shape_2,z_tmp,r_tmp,C); %平移到了中间% 我看不懂魔法数字
                f2.EdgeColor="none";
                view(app.UIAxes2,see_where,see_high);  % 设置视点位置
                axis(app.UIAxes2,[-100,100,0,L,0,1])
                %axis(app.UIAxes2,'off')
                xlabel(app.UIAxes2,'压强(Pa)');
                drawnow
                app.UIAxes2.NextPlot='replacechildren';
            end
            pause(0.1)
        end

        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            a0=double(floor(app.Slider_3.Value*100)/100);
            app.EditField_a0.Value=a0;
            EditField_a0ValueChanged(app, event)

        end

        % Callback function
        function Slider_4ValueChanged(app, event)
            z0=app.Slider_4.Value;
            app.EditField_z0.Value=double(floor(z0*100)/100);
            EditField_a0ValueChanged(app, event)

        end

        % Value changed function: EditField_z0
        function EditField_z0ValueChanged(app, event)
            z0=app.Slider_4.Value;
            app.EditField_z0.Value=z0;
            EditField_a0ValueChanged(app, event)

        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            page4_1
            delete(app.UIFigure)

        end

        % Value changed function: Slider_6
        function Slider_6ValueChanged(app, event)
            z0=app.Slider_6.Value;
            app.EditField_z0.Value=double(floor(z0*100)/100);
            EditField_a0ValueChanged(app, event)

        end

        % Value changed function: EditField_theta_change
        function EditField_theta_changeValueChanged(app, event)
            theta0= app.EditField_theta_change.Value;
            app.Slider_5.Value=theta0;
            EditField_a0ValueChanged(app, event)

        end

        % Value changed function: Slider_5
        function Slider_5ValueChanged(app, event)
            theta0=app.Slider_5.Value;
            app.EditField_theta_change.Value=double(floor(theta0*100)/100);
            EditField_a0ValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_3 = uiaxes(app.UIFigure);
            app.UIAxes2_3.XTick = [];
            app.UIAxes2_3.YTick = [];
            app.UIAxes2_3.ZTick = [];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '方位角(°)';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [-90 90];
            app.Slider.MajorTicks = [-90 -45 0 45 90];
            app.Slider.MajorTickLabels = {'-90', '-45', '0', '45', '90'};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [-90 -81 -72 -63 -54 -45 -36 -27 -18 -9 0 9 18 27 36 45 54 63 72 81 90];
            app.Slider.Value = 75;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '仰角(°)';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [-90 90];
            app.Slider_2.MajorTicks = [-90 -45 0 45 90];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.Value = 20;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed2, true);
            app.Button.Text = '不同模式下的振动';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_2.Text = ' 给定初始条件的振动';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed2, true);
            app.Button_3.Text = '膜上某点的振动';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed2, true);
            app.Button_4.Text = '参数设置';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed2, true);
            app.Button_5.Text = '数据导出';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed2, true);
            app.Button_6.Text = '腔内某点的振动';
            app.a0aLabel = uilabel(app.UIFigure);
            app.a0aLabel.Text = '敲击位置';
            app.EditField_a0 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_a0.ValueDisplayFormat = '%.2f';
            app.EditField_a0.ValueChangedFcn = createCallbackFcn(app, @EditField_a0ValueChanged, true);
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_3.Limits = [0 1];
            app.Slider_3.MajorTicks = [0 0.5 1];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.EditField_z0 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_z0.ValueChangedFcn = createCallbackFcn(app, @EditField_z0ValueChanged, true);
            app.EditField_z0.Value = 0.5;
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '频谱与声音';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = '空气腔截面';
            app.Button_xy = uiradiobutton(app.ButtonGroup);
            app.Button_xy.Text = '横截面';
            app.Button_xy.Value = true;
            app.Button_z = uiradiobutton(app.ButtonGroup);
            app.Button_z.Text = '纵截面';
            app.Slider_5 = uislider(app.UIFigure);
            app.Slider_5.Limits = [0 180];
            app.Slider_5.MajorTicks = [0 90 180];
            app.Slider_5.MajorTickLabels = {'0', '90', '180'};
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @Slider_5ValueChanged, true);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = ' θ(°)';
            app.EditField_theta_change = uieditfield(app.UIFigure, 'numeric');
            app.EditField_theta_change.ValueChangedFcn = createCallbackFcn(app, @EditField_theta_changeValueChanged, true);
            app.zLLabel = uilabel(app.UIFigure);
            app.zLLabel.Text = ' z/L';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '切面位置';
            app.Slider_6 = uislider(app.UIFigure);
            app.Slider_6.Limits = [0 1];
            app.Slider_6.MajorTicks = [0 0.5 1];
            app.Slider_6.ValueChangedFcn = createCallbackFcn(app, @Slider_6ValueChanged, true);
            app.Slider_6.Value = 0.5;
            app.a0aLabel_2 = uilabel(app.UIFigure);
            app.a0aLabel_2.Text = 'a₀/a';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'point_1.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = page4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end