classdef page4_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        ButtonGroup      matlab.ui.container.ButtonGroup
        Button_log       matlab.ui.control.RadioButton
        Button_line      matlab.ui.control.RadioButton
        Button_9         matlab.ui.control.Button
        EditField_theta  matlab.ui.control.NumericEditField
        Label_2          matlab.ui.control.Label
        EditField_r      matlab.ui.control.NumericEditField
        Label            matlab.ui.control.Label
        Slider_2         matlab.ui.control.Slider
        Slider_2Label    matlab.ui.control.Label
        Slider           matlab.ui.control.Slider
        SliderLabel      matlab.ui.control.Label
        Button_8         matlab.ui.control.Button
        Button_6         matlab.ui.control.Button
        Button_4         matlab.ui.control.Button
        Button_3         matlab.ui.control.Button
        Button_2         matlab.ui.control.Button
        UIAxes2_3        matlab.ui.control.UIAxes
        UIAxes2_2        matlab.ui.control.UIAxes
        UIAxes           matlab.ui.control.UIAxes
        UIAxes2          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EditField_theta
        function EditField_thetaValueChanged(app, event)
            app.UIAxes.NextPlot='replacechildren';
  
            th_n= app.EditField_theta.Value;
            r_n=app.EditField_r.Value;
            load('.\para.mat')
            load('.\rootBessel.mat');
            load('.\rootBesselDiff.mat');
            load('.\OmegaRe.mat')
            %a=1; %归一化后
            r_num=100;
            theta_num=100;% 切分的数目
            order_max=10;
            r=linspace(0,1,r_num)';
            theta=linspace(0,2*pi-2*pi/theta_num,theta_num);
            n0=th_n;

            shape_m=cell(1,order_max);
            for mm=1:order_max
                z1=sqrt(2)*besselj(n0,rootBessel(n0+1,mm)*r)/...
                    besselj(n0+1,rootBessel(n0+1,mm))*...
                    cos(n0*theta)/sqrt(pi)/sqrt(1+(n0==0));
                % 先是r,后是theta
                shape_m{mm}=z1;
            end

            z_max=zeros(1,order_max);
            for mm=1:order_max
                z_max(mm)=max(max(shape_m{mm}));
            end

            %load('.\para.mat')
            load('.\rootBessel.mat');
            load('.\rootBesselDiff.mat');
            load('.\OmegaRe.mat')

            ome_mem=rootBessel(th_n+1,:)*cs_ca;
            %ome_cap=sqrt((rootBesselDiff(th_n+1,:)*ca/a).^2+0.25*pi*pi/L/L)/2/pi;
            ome_cap_1=sqrt(rootBesselDiff(th_n+1,:).^2+(pi./L_a).^2);
            ome_cap_2=sqrt(rootBesselDiff(th_n+1,:).^2+(2*pi./L_a).^2);
            ome_cap_3=sqrt(rootBesselDiff(th_n+1,:).^2+(3*pi./L_a).^2);
            ome_cap_4=sqrt(rootBesselDiff(th_n+1,:).^2+(4*pi./L_a).^2);
            ome_cap_5=sqrt(rootBesselDiff(th_n+1,:).^2+(5*pi./L_a).^2);
            ome_cap=[ome_cap_1(1,1:10) ome_cap_2(1,1:10) ome_cap_3(1,1:10) ome_cap_4(1,1:10) ome_cap_5(1,1:10)];
            save('omega_m_c_a.mat','Omega','ome_mem','ome_cap');
            
if app.Button_line.Value==true;
     li_mem=plot(app.UIAxes,ome_mem(1,1:10),3*ones(1,10),'.','Color','r','MarkerSize',10);
            app.UIAxes.NextPlot='add';
            li_cap=plot(app.UIAxes,ome_cap,2*ones(1,50),'.','Color','g','MarkerSize',10);
            li_all=plot(app.UIAxes,Omega(1,1:10),ones(1,10),'.','Color','y','MarkerSize',10);
            app.UIAxes.XScale='linear';
else
       lo_mem=semilogx(app.UIAxes,ome_mem(1,1:10),3*ones(1,10),'.','Color','r','MarkerSize',10);
            app.UIAxes.NextPlot='add';
            lo_cap=semilogx(app.UIAxes,ome_cap,2*ones(1,50),'.','Color','g','MarkerSize',10);
            lo_all=semilogx(app.UIAxes,Omega(1,1:10),ones(1,10),'.','Color','y','MarkerSize',10);
            app.UIAxes.XScale='log';
end

            n=th_n;
            m=r_n;
            cnt=0;
            mkdir(sprintf('./couple/fig/%d.%d/',n,m));
            omega_tmp=Omega(n+1,m);
            for t=linspace(0,2*pi/omega_tmp,100)
                cnt=cnt+1;
                s=surfShape(app.UIAxes2_2,shape_m{m}*cos(omega_tmp*t),r,theta);
                s.EdgeColor="none";
                axis(app.UIAxes2_2,[-1,1,-1,1,-1.5,1.5]);
                load('s_w0.mat');
                load('s_h0.mat');
                view(app.UIAxes2_2,see_where0,see_high0);  % 设置视点位置
                axis off
                drawnow
                pause(0.05)
                %        saveas(gcf,sprintf("./couple/fig/%d.%d/%d.bmp",n,m,cnt))
                load('.\omega_m_c_a.mat')

            end


        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            see_where0=app.Slider.Value;
            save('s_w0.mat','see_where0');
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            see_high0=app.Slider_2.Value;
            save('s_h0.mat','see_high0');
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
%                 if app.Button_log.Value == true;
%                     app.UIAxes.Visible='off';
%                     app.UIAxes_2.Visible='on';
%                 elseif app.Button_line.Value ==true;
%                     app.UIAxes.Visible='on';
%                     app.UIAxes_2.Visible='off';
%                 end
load('omega_m_c_a.mat');
if app.Button_line.Value==true;
     li_mem=plot(app.UIAxes,ome_mem(1,1:10),3*ones(1,10),'.','Color','r','MarkerSize',10);
            app.UIAxes.NextPlot='add';
            li_cap=plot(app.UIAxes,ome_cap,2*ones(1,50),'.','Color','g','MarkerSize',10);
            li_all=plot(app.UIAxes,Omega(1,1:10),ones(1,10),'.','Color','y','MarkerSize',10);
            app.UIAxes.XScale='linear';
else
       lo_mem=semilogx(app.UIAxes,ome_mem(1,1:10),3*ones(1,10),'.','Color','r','MarkerSize',10);
            app.UIAxes.NextPlot='add';
            lo_cap=semilogx(app.UIAxes,ome_cap,2*ones(1,50),'.','Color','g','MarkerSize',10);
            lo_all=semilogx(app.UIAxes,Omega(1,1:10),ones(1,10),'.','Color','y','MarkerSize',10);
            app.UIAxes.XScale='log';
end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '频谱')
            xlabel(app.UIAxes, '特征频率')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 20];
            app.UIAxes.YLim = [0 4];
            app.UIAxes.XTick = [0 5 10 15 20];
            app.UIAxes.XTickLabel = {'0'; '5'; '10'; '15'; '20'};
            app.UIAxes.YTick = [0 1 2 3 4];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ZTick = [];
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            colormap(app.UIAxes2_2, 'autumn')
            app.UIAxes2_3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2_3, 'X')
            ylabel(app.UIAxes2_3, 'Y')
            zlabel(app.UIAxes2_3, 'Z')
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Text = ' 不同模式下的振动';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.Text = ' 给定初始条件的振动';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.Text = ' 鼓上某点的振动';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.Text = '参数设置';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.Text = '数据导出';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = '方位角';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [-90 90];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_2Label = uilabel(app.UIFigure);
            app.Slider_2Label.Text = '仰角';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [-90 90];
            app.Slider_2.MajorTicks = [-90 -45 0 45 90];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'r方向模式数';
            app.EditField_r = uieditfield(app.UIFigure, 'numeric');
            app.EditField_r.Value = 1;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'θ方向模式数';
            app.EditField_theta = uieditfield(app.UIFigure, 'numeric');
            app.EditField_theta.ValueChangedFcn = createCallbackFcn(app, @EditField_thetaValueChanged, true);
            app.EditField_theta.Value = 1;
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.Text = ' 鼓上某点的振动';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = ' 横轴';
            app.Button_line = uiradiobutton(app.ButtonGroup);
            app.Button_line.Text = '线性坐标';
            app.Button_line.Value = true;
            app.Button_log = uiradiobutton(app.ButtonGroup);
            app.Button_log.Text = '对数坐标';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = page4_0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end