classdef page4_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        EditField_18  matlab.ui.control.EditField
        EditField_17  matlab.ui.control.EditField
        EditField_16  matlab.ui.control.EditField
        EditField_15  matlab.ui.control.EditField
        Label_3       matlab.ui.control.Label
        EditField_14  matlab.ui.control.EditField
        EditField_13  matlab.ui.control.NumericEditField
        EditField_11  matlab.ui.control.NumericEditField
        EditField_9   matlab.ui.control.NumericEditField
        EditField_7   matlab.ui.control.NumericEditField
        EditField     matlab.ui.control.NumericEditField
        Label         matlab.ui.control.Label
        Button_8      matlab.ui.control.Button
        Button_7      matlab.ui.control.Button
        Button_6      matlab.ui.control.Button
        Button_5      matlab.ui.control.Button
        Button_4      matlab.ui.control.Button
        Button_3      matlab.ui.control.Button
        Button_2      matlab.ui.control.Button
        Button        matlab.ui.control.Button
        UIAxes        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            load('.\ft_x.mat');
            load('.\ft_y.mat');
            load('.\OmegaRe.mat');
            load('.\para.mat');
            
            plot(app.UIAxes,x,y,'b','LineWidth',2);
            [f_n_x,f_m_x]=findpeaks(y,'SortStr','descend');
            f_m_x=(f_m_x-1)/length(x)*max(x);
            f_m_x=f_m_x(1:5);
            omega_f=Omega*ca/a/2/pi;
            [o_m1,o_n1]=find(abs(omega_f-f_m_x(1))==min(min(abs(omega_f-f_m_x(1)))));
            o_n1=num2str(o_n1-1);
            o_m1=num2str(o_m1);
            app.EditField_14.Value=['[' o_n1 ',' o_m1 ']'];
            [o_m2,o_n2]=find(abs(omega_f-f_m_x(2))==min(min(abs(omega_f-f_m_x(2)))));
            o_n2=num2str(o_n2-1);
            o_m2=num2str(o_m2);
            app.EditField_15.Value=['[' o_n2 ',' o_m2 ']'];
            [o_m3,o_n3]=find(abs(omega_f-f_m_x(3))== min(min(abs(omega_f-f_m_x(3)))));
            o_n3=num2str(o_n3-1);
            o_m3=num2str(o_m3);
            app.EditField_16.Value=['[' o_n3 ',' o_m3 ']'];
            [o_m4,o_n4]=find(abs(omega_f-f_m_x(4))== min(min(abs(omega_f-f_m_x(4)))));
            o_n4=num2str(o_n4-1);
            o_m4=num2str(o_m4);
            app.EditField_17.Value=['[' o_n4 ',' o_m4 ']'];
            [o_m5,o_n5]=find(abs(omega_f-f_m_x(5))== min(min(abs(omega_f-f_m_x(5)))));
            o_n5=num2str(o_n5-1);
            o_m5=num2str(o_m5);
            app.EditField_18.Value=['[' o_n5 ',' o_m5 ']'];

            app.EditField.Value=double(floor(f_m_x(1)*100)/100);
            app.EditField_7.Value=double(floor(f_m_x(2)*100)/100);
            app.EditField_9.Value=double(floor(f_m_x(3)*100)/100);
            app.EditField_11.Value=double(floor(f_m_x(4)*100)/100);
            app.EditField_13.Value=double(floor(f_m_x(5)*100)/100);
            xlim(app.UIAxes,[0,1500])
            title(app.UIAxes,'频谱','Color','w')
            
            
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            load('.\para.mat');
            load('.\wcn.mat');
            load('.\Omega.mat');
            load('.\cut_num.mat');
            fn=10000; % 横轴划分的个数
            omega_limit=10;ca=340;a=0.5;
            x=linspace(0,omega_limit*ca/a/2/pi,fn);
            y=zeros(1,fn);
            for nn=0:order_max
                for mm=1:order_max
                    y=y+abs(w_cn(nn+1,mm)*Omega(nn+1,mm))./(1+(x-Omega(nn+1,mm)*ca/a/2/pi).^2/10);
                end
            end
            y=y/max(y);
            fs=10000;
            NN=3000;

            % 我看不懂在写啥
                        yy=y(1).*sin(2*pi.*x(1).*(0:NN-1)./fs);
                        for i=2:length(y)
                            yy=y+AA(i).*sin(2*pi.*x(i).*(0:NN-1)./fs);
                        end
                        sound(yy,fs);

        end

        % Callback function
        function Button_7Pushed(app, event)

        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            page3
            delete(app.UIFigure)
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            page4_0
            delete(app.UIFigure)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            page4
            delete(app.UIFigure)            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            page_5_0
            delete(app.UIFigure)
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            page5_1
            delete(app.UIFigure)
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            page6
            delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '频谱')
            xlabel(app.UIAxes, '频率(Hz)')
            ylabel(app.UIAxes, '响度(A.U.)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 500 1000 1500 2000];
            app.UIAxes.XTickLabel = {'0'; '500'; '1000'; '1500'; '2000'};
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '不同模式下的振动';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = ' 给定初始条件的振动';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '膜上某点的振动';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '参数设置';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '数据导出';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '腔内某点的振动';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.Text = '频谱与声音';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '播放声音';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '主要频率';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueDisplayFormat = '%.2f';
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_7.ValueDisplayFormat = '%.2f';
            app.EditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_9.ValueDisplayFormat = '%.2f';
            app.EditField_11 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_11.ValueDisplayFormat = '%.2f';
            app.EditField_13 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_13.ValueDisplayFormat = '%.2f';
            app.EditField_14 = uieditfield(app.UIFigure, 'text');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '模式数[n,m]';
            app.EditField_15 = uieditfield(app.UIFigure, 'text');
            app.EditField_16 = uieditfield(app.UIFigure, 'text');
            app.EditField_17 = uieditfield(app.UIFigure, 'text');
            app.EditField_18 = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = page4_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end