classdef page5_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Slider_5               matlab.ui.control.Slider
        Slider_5Label          matlab.ui.control.Label
        Slider_4               matlab.ui.control.Slider
        Slider_4Label          matlab.ui.control.Label
        EditField_h_change     matlab.ui.control.NumericEditField
        Label_11               matlab.ui.control.Label
        Label_10               matlab.ui.control.Label
        Slider_3               matlab.ui.control.Slider
        EditField_xita_change  matlab.ui.control.NumericEditField
        Label_9                matlab.ui.control.Label
        EditField_r_change     matlab.ui.control.NumericEditField
        Label_8                matlab.ui.control.Label
        Label_7                matlab.ui.control.Label
        Label_6                matlab.ui.control.Label
        Label_5                matlab.ui.control.Label
        Label_4                matlab.ui.control.Label
        Label_3                matlab.ui.control.Label
        Label_2                matlab.ui.control.Label
        Slider_2               matlab.ui.control.Slider
        Label                  matlab.ui.control.Label
        Slider                 matlab.ui.control.Slider
        Button_6               matlab.ui.control.Button
        Button_5               matlab.ui.control.Button
        Button_4               matlab.ui.control.Button
        Button_3               matlab.ui.control.Button
        Button_2               matlab.ui.control.Button
        Button                 matlab.ui.control.Button
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EditField_r_change
        function EditField_r_changeValueChanged(app, event)
            load('.\para.mat')
            r0=app.EditField_r_change.Value*a;
            h0=app.EditField_h_change.Value*L;
            app.Slider.Value=double(app.EditField_r_change.Value);
            xita0=app.EditField_xita_change.Value;
            x0=r0*cos(xita0/180*pi);
            y0=r0*sin(xita0/180*pi);
            hh=0:0.001:L;
            tt=0:pi/200:2*pi;
            xx=zeros(length(hh),length(tt));
            yy=zeros(length(hh),length(tt));
            zz=zeros(length(hh),length(tt));
            for ix =1:length(hh)
                xx(ix,:)=a.*cos(tt);
            end
            for ii =1:length(hh)
                zz(ii,:)=a.*sin(tt);
            end
            for iI =1:length(hh)
                yy(iI,:)=hh(iI);
            end
            drum_draw=surf(app.UIAxes2,xx,yy,zz);
            app.UIAxes2.NextPlot='add';
            set(drum_draw,'edgecolor','none');
            drum_draw.FaceAlpha=0.4;
            view(app.UIAxes2,180,-120);  
            axis(app.UIAxes2,[-1.1*a,1.1*a,-1.1*L,1.1*L,-1.1*a,1.1*a]);
            tthh=linspace(0,2*pi,100);
            xx=a.*cos(tthh);
            yy=zeros(size(xx));
            zz=a.*sin(tthh);
            mem_draw=fill3(app.UIAxes2,xx,yy,zz,[1,0.95,0.8]);
            mem_draw.FaceAlpha=0.5;
            app.UIAxes2.NextPlot='add';
            plot3(app.UIAxes2,0,0,0,'.','color','k','MarkerSize',10);
            plot3(app.UIAxes2,a0*a,0,0,'.','color','r','MarkerSize',15);
            plot3(app.UIAxes2,x0,h0,y0,'.','color','b','MarkerSize',15);    
            load('s_w00.mat');
            load('s_h00.mat');
            view(app.UIAxes2,see_where00,see_high00);  % 设置视点位置
            app.UIAxes2.NextPlot='replacechildren';
        end

        % Value changed function: Slider_4
        function Slider_4ValueChanged(app, event)
            see_where00=app.Slider_4.Value/180;
            save('s_w00.mat','see_where00');
            load('.\para.mat')
            r0=app.EditField_r_change.Value*a;
            h0=app.EditField_h_change.Value*L;
            xita0=app.EditField_xita_change.Value;
            x0=r0*cos(xita0/180*pi);
            y0=r0*sin(xita0/180*pi);
            hh=0:0.001:L;
            tt=0:pi/200:2*pi;
            xx=zeros(length(hh),length(tt));
            yy=zeros(length(hh),length(tt));
            zz=zeros(length(hh),length(tt));
            for ix =1:length(hh)
                xx(ix,:)=a.*cos(tt);
            end
            for ii =1:length(hh)
                zz(ii,:)=a.*sin(tt);
            end
            for iI =1:length(hh)
                yy(iI,:)=hh(iI);
            end
            drum_draw=surf(app.UIAxes2,xx,yy,zz);
            app.UIAxes2.NextPlot='add';
            set(drum_draw,'edgecolor','none');
            drum_draw.FaceAlpha=0.4;
            view(app.UIAxes2,180,-120);  
            axis(app.UIAxes2,[-1.1*a,1.1*a,-1.1*L,1.1*L,-1.1*a,1.1*a]);
            tthh=linspace(0,2*pi,100);
            xx=a.*cos(tthh);
            yy=zeros(size(xx));
            zz=a.*sin(tthh);
            mem_draw=fill3(app.UIAxes2,xx,yy,zz,[1,0.95,0.8]);
            mem_draw.FaceAlpha=0.5;
            app.UIAxes2.NextPlot='add';
            plot3(app.UIAxes2,0,0,0,'.','color','k','MarkerSize',10);
            plot3(app.UIAxes2,a0*a,0,0,'.','color','r','MarkerSize',15);
            plot3(app.UIAxes2,x0,h0,y0,'.','color','b','MarkerSize',15);    
            load('s_w00.mat');
            load('s_h00.mat');
            view(app.UIAxes2,[sin(see_where00/180*pi),see_high00,cos(see_where00/180*pi)]);  % 设置视点位置
            app.UIAxes2.NextPlot='replacechildren';

        end

        % Value changed function: Slider_5
        function Slider_5ValueChanged(app, event)
            see_high00=app.Slider_5.Value/180;
            save('s_h00.mat','see_high00');
            load('.\para.mat')
            r0=app.EditField_r_change.Value*a;
            h0=app.EditField_h_change.Value*L;
            xita0=app.EditField_xita_change.Value;
            x0=r0*cos(xita0/180*pi);
            y0=r0*sin(xita0/180*pi);
            hh=0:0.001:L;
            tt=0:pi/200:2*pi;
            xx=zeros(length(hh),length(tt));
            yy=zeros(length(hh),length(tt));
            zz=zeros(length(hh),length(tt));
            for ix =1:length(hh)
                xx(ix,:)=a.*cos(tt);
            end
            for ii =1:length(hh)
                zz(ii,:)=a.*sin(tt);
            end
            for iI =1:length(hh)
                yy(iI,:)=hh(iI);
            end
            drum_draw=surf(app.UIAxes2,xx,yy,zz);
            app.UIAxes2.NextPlot='add';
            set(drum_draw,'edgecolor','none');
            drum_draw.FaceAlpha=0.4;
            view(app.UIAxes2,180,-120);  
            axis(app.UIAxes2,[-1.1*a,1.1*a,-1.1*L,1.1*L,-1.1*a,1.1*a]);
            tthh=linspace(0,2*pi,100);
            xx=a.*cos(tthh);
            yy=zeros(size(xx));
            zz=a.*sin(tthh);
            mem_draw=fill3(app.UIAxes2,xx,yy,zz,[1,0.95,0.8]);
            mem_draw.FaceAlpha=0.5;
            app.UIAxes2.NextPlot='add';
            plot3(app.UIAxes2,0,0,0,'.','color','k','MarkerSize',10);
            plot3(app.UIAxes2,a0*a,0,0,'.','color','r','MarkerSize',15);
            plot3(app.UIAxes2,x0,h0,y0,'.','color','b','MarkerSize',15);    
            load('s_w00.mat');
            load('s_h00.mat');
            view(app.UIAxes2,[sin(see_where00/180*pi),see_high00,cos(see_where00/180*pi)]);  % 设置视点位置
            app.UIAxes2.NextPlot='replacechildren';

        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            r0=floor(app.Slider.Value*100)/100;
            app.EditField_r_change.Value=r0;
            load('.\para.mat')
            r0=app.EditField_r_change.Value*a;
            h0=app.EditField_h_change.Value*L;
            app.Slider.Value=double(app.EditField_r_change.Value);
            xita0=app.EditField_xita_change.Value;
            x0=r0*cos(xita0/180*pi);
            y0=r0*sin(xita0/180*pi);
            hh=0:0.001:L;
            tt=0:pi/200:2*pi;
            xx=zeros(length(hh),length(tt));
            yy=zeros(length(hh),length(tt));
            zz=zeros(length(hh),length(tt));
            for ix =1:length(hh)
                xx(ix,:)=a.*cos(tt);
            end
            for ii =1:length(hh)
                zz(ii,:)=a.*sin(tt);
            end
            for iI =1:length(hh)
                yy(iI,:)=hh(iI);
            end
            drum_draw=surf(app.UIAxes2,xx,yy,zz);
            app.UIAxes2.NextPlot='add';
            set(drum_draw,'edgecolor','none');
            drum_draw.FaceAlpha=0.4;
            view(app.UIAxes2,180,-120);  
            axis(app.UIAxes2,[-1.1*a,1.1*a,-1.1*L,1.1*L,-1.1*a,1.1*a]);
            tthh=linspace(0,2*pi,100);
            xx=a.*cos(tthh);
            yy=zeros(size(xx));
            zz=a.*sin(tthh);
            mem_draw=fill3(app.UIAxes2,xx,yy,zz,[1,0.95,0.8]);
            mem_draw.FaceAlpha=0.5;
            app.UIAxes2.NextPlot='add';
            plot3(app.UIAxes2,0,0,0,'.','color','k','MarkerSize',10);
            plot3(app.UIAxes2,a0*a,0,0,'.','color','r','MarkerSize',15);
            plot3(app.UIAxes2,x0,h0,y0,'.','color','b','MarkerSize',15);    
            load('s_w00.mat');
            load('s_h00.mat');
            view(app.UIAxes2,see_where00,see_high00);  % 设置视点位置
            app.UIAxes2.NextPlot='replacechildren';

        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            xita0=floor(app.Slider_2.Value*100)/100;
            app.EditField_xita_change.Value=xita0;
            load('.\para.mat')
            r0=app.EditField_r_change.Value*a;
            h0=app.EditField_h_change.Value*L;
            app.Slider.Value=double(app.EditField_r_change.Value);
            xita0=app.EditField_xita_change.Value;
            x0=r0*cos(xita0/180*pi);
            y0=r0*sin(xita0/180*pi);
            hh=0:0.001:L;
            tt=0:pi/200:2*pi;
            xx=zeros(length(hh),length(tt));
            yy=zeros(length(hh),length(tt));
            zz=zeros(length(hh),length(tt));
            for ix =1:length(hh)
                xx(ix,:)=a.*cos(tt);
            end
            for ii =1:length(hh)
                zz(ii,:)=a.*sin(tt);
            end
            for iI =1:length(hh)
                yy(iI,:)=hh(iI);
            end
            drum_draw=surf(app.UIAxes2,xx,yy,zz);
            app.UIAxes2.NextPlot='add';
            set(drum_draw,'edgecolor','none');
            drum_draw.FaceAlpha=0.4;
            view(app.UIAxes2,180,-120);  
            axis(app.UIAxes2,[-1.1*a,1.1*a,-1.1*L,1.1*L,-1.1*a,1.1*a]);
            tthh=linspace(0,2*pi,100);
            xx=a.*cos(tthh);
            yy=zeros(size(xx));
            zz=a.*sin(tthh);
            mem_draw=fill3(app.UIAxes2,xx,yy,zz,[1,0.95,0.8]);
            mem_draw.FaceAlpha=0.5;
            app.UIAxes2.NextPlot='add';
            plot3(app.UIAxes2,0,0,0,'.','color','k','MarkerSize',10);
            plot3(app.UIAxes2,a0*a,0,0,'.','color','r','MarkerSize',15);
            plot3(app.UIAxes2,x0,h0,y0,'.','color','b','MarkerSize',15);    
            load('s_w00.mat');
            load('s_h00.mat');
            view(app.UIAxes2,see_where00,see_high00);  % 设置视点位置
            app.UIAxes2.NextPlot='replacechildren';
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            h0=floor(app.Slider_3.Value*100)/100;
            app.EditField_h_change.Value=h0;
            load('.\para.mat')
            r0=app.EditField_r_change.Value*a;
            h0=app.EditField_h_change.Value*L;
            app.Slider.Value=double(app.EditField_r_change.Value);
            xita0=app.EditField_xita_change.Value;
            x0=r0*cos(xita0/180*pi);
            y0=r0*sin(xita0/180*pi);
            hh=0:0.001:L;
            tt=0:pi/200:2*pi;
            xx=zeros(length(hh),length(tt));
            yy=zeros(length(hh),length(tt));
            zz=zeros(length(hh),length(tt));
            for ix =1:length(hh)
                xx(ix,:)=a.*cos(tt);
            end
            for ii =1:length(hh)
                zz(ii,:)=a.*sin(tt);
            end
            for iI =1:length(hh)
                yy(iI,:)=hh(iI);
            end
            drum_draw=surf(app.UIAxes2,xx,yy,zz);
            app.UIAxes2.NextPlot='add';
            set(drum_draw,'edgecolor','none');
            drum_draw.FaceAlpha=0.4;
            view(app.UIAxes2,180,-120);  
            axis(app.UIAxes2,[-1.1*a,1.1*a,-1.1*L,1.1*L,-1.1*a,1.1*a]);
            tthh=linspace(0,2*pi,100);
            xx=a.*cos(tthh);
            yy=zeros(size(xx));
            zz=a.*sin(tthh);
            mem_draw=fill3(app.UIAxes2,xx,yy,zz,[1,0.95,0.8]);
            mem_draw.FaceAlpha=0.5;
            app.UIAxes2.NextPlot='add';
            plot3(app.UIAxes2,0,0,0,'.','color','k','MarkerSize',10);
            plot3(app.UIAxes2,a0*a,0,0,'.','color','r','MarkerSize',15);
            plot3(app.UIAxes2,x0,h0,y0,'.','color','b','MarkerSize',15);    
            load('s_w00.mat');
            load('s_h00.mat');
            view(app.UIAxes2,see_where00,see_high00);  % 设置视点位置
            app.UIAxes2.NextPlot='replacechildren';
        end

        % Value changed function: EditField_xita_change
        function EditField_xita_changeValueChanged(app, event)
            load('.\para.mat')
            r0=app.EditField_r_change.Value*a;
            xita0=app.EditField_xita_change.Value;
            h0=app.EditField_h_change.Value*L;
            app.Slider_2.Value=xita0;
            xita0=app.EditField_xita_change.Value;
            x0=r0*cos(xita0/180*pi);
            y0=r0*sin(xita0/180*pi);
            hh=0:0.001:L;
            tt=0:pi/200:2*pi;
            xx=zeros(length(hh),length(tt));
            yy=zeros(length(hh),length(tt));
            zz=zeros(length(hh),length(tt));
            for ix =1:length(hh)
                xx(ix,:)=a.*cos(tt);
            end
            for ii =1:length(hh)
                zz(ii,:)=a.*sin(tt);
            end
            for iI =1:length(hh)
                yy(iI,:)=hh(iI);
            end
            drum_draw=surf(app.UIAxes2,xx,yy,zz);
            app.UIAxes2.NextPlot='add';
            set(drum_draw,'edgecolor','none');
            drum_draw.FaceAlpha=0.4;
            view(app.UIAxes2,180,-120);  
            axis(app.UIAxes2,[-1.1*a,1.1*a,-1.1*L,1.1*L,-1.1*a,1.1*a]);
            tthh=linspace(0,2*pi,100);
            xx=a.*cos(tthh);
            yy=zeros(size(xx));
            zz=a.*sin(tthh);
            mem_draw=fill3(app.UIAxes2,xx,yy,zz,[1,0.95,0.8]);
            mem_draw.FaceAlpha=0.5;
            app.UIAxes2.NextPlot='add';
            plot3(app.UIAxes2,0,0,0,'.','color','k','MarkerSize',10);
            plot3(app.UIAxes2,a0*a,0,0,'.','color','r','MarkerSize',15);
            plot3(app.UIAxes2,x0,h0,y0,'.','color','b','MarkerSize',15);    
            load('s_w00.mat');
            load('s_h00.mat');
            view(app.UIAxes2,see_where00,see_high00);  % 设置视点位置
            app.UIAxes2.NextPlot='replacechildren';


        end

        % Value changed function: EditField_h_change
        function EditField_h_changeValueChanged(app, event)
            load('.\para.mat')
            r0=app.EditField_r_change.Value*a;
            h0=app.EditField_h_change.Value;
            xita0=app.EditField_xita_change.Value*L;
            app.Slider_3.Value=h0;
            x0=r0*cos(xita0/180*pi);
            y0=r0*sin(xita0/180*pi);
            h0=h0*L;
            hh=0:0.001:L;
            tt=0:pi/200:2*pi;
            xx=zeros(length(hh),length(tt));
            yy=zeros(length(hh),length(tt));
            zz=zeros(length(hh),length(tt));
            for ix =1:length(hh)
                xx(ix,:)=a.*cos(tt);
            end
            for ii =1:length(hh)
                zz(ii,:)=a.*sin(tt);
            end
            for iI =1:length(hh)
                yy(iI,:)=hh(iI);
            end
            drum_draw=surf(app.UIAxes2,xx,yy,zz);
            app.UIAxes2.NextPlot='add';
            set(drum_draw,'edgecolor','none');
            drum_draw.FaceAlpha=0.4;
            view(app.UIAxes2,180,-120);  
            axis(app.UIAxes2,[-1.1*a,1.1*a,-1.1*L,1.1*L,-1.1*a,1.1*a]);
            tthh=linspace(0,2*pi,100);
            xx=a.*cos(tthh);
            yy=zeros(size(xx));
            zz=a.*sin(tthh);
            mem_draw=fill3(app.UIAxes2,xx,yy,zz,[1,0.95,0.8]);
            mem_draw.FaceAlpha=0.5;
            app.UIAxes2.NextPlot='add';
            plot3(app.UIAxes2,0,0,0,'.','color','k','MarkerSize',10);
            plot3(app.UIAxes2,a0*a,0,0,'.','color','r','MarkerSize',15);
            plot3(app.UIAxes2,x0,h0,y0,'.','color','b','MarkerSize',15);    
            load('s_w00.mat');
            load('s_h00.mat');
            view(app.UIAxes2,see_where00,see_high00);  % 设置视点位置
            app.UIAxes2.NextPlot='replacechildren';

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '频谱')
            xlabel(app.UIAxes, '时间(s)')
            ylabel(app.UIAxes, '振幅(A.U.)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 500 1000 1500 2000];
            app.UIAxes.XTickLabel = {'0'; '500'; '1000'; '1500'; '2000'};
            app.UIAxes.YTick = [-1 0 1];
            app.UIAxes.YTickLabel = {'-1.0'; '1'};
            app.UIAxes.ZTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ZTick = [];
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Text = ' 不同模式下的振动';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Text = ' 给定初始条件的振动';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.Text = '膜上某点的振动';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.Text = ' 参数设置';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.Text = ' 数据导出';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.Text = ' 腔内某点的振动';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1];
            app.Slider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '*a';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [0 180];
            app.Slider_2.MajorTicks = [0 45 90 135 180];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '°';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '°';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '°';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '°';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '°';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '°';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '观察点位置 r';
            app.EditField_r_change = uieditfield(app.UIFigure, 'numeric');
            app.EditField_r_change.ValueChangedFcn = createCallbackFcn(app, @EditField_r_changeValueChanged, true);
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '观察点位置 θ';
            app.EditField_xita_change = uieditfield(app.UIFigure, 'numeric');
            app.EditField_xita_change.ValueChangedFcn = createCallbackFcn(app, @EditField_xita_changeValueChanged, true);
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_3.Limits = [0 1];
            app.Slider_3.MajorTicks = [0 0.25 0.5 0.75 1];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '*L';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '观察点位置 h';
            app.EditField_h_change = uieditfield(app.UIFigure, 'numeric');
            app.EditField_h_change.ValueChangedFcn = createCallbackFcn(app, @EditField_h_changeValueChanged, true);
            app.Slider_4Label = uilabel(app.UIFigure);
            app.Slider_4Label.Text = '方位角';
            app.Slider_4 = uislider(app.UIFigure);
            app.Slider_4.Limits = [-90 90];
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanged, true);
            app.Slider_5Label = uilabel(app.UIFigure);
            app.Slider_5Label.Text = '仰角';
            app.Slider_5 = uislider(app.UIFigure);
            app.Slider_5.Limits = [-180 180];
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @Slider_5ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = page5_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end