classdef page6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Button_9     matlab.ui.control.Button
        EditField_2  matlab.ui.control.EditField
        Label_2      matlab.ui.control.Label
        Button_8     matlab.ui.control.Button
        Button_7     matlab.ui.control.Button
        CheckBox_5   matlab.ui.control.CheckBox
        CheckBox_4   matlab.ui.control.CheckBox
        CheckBox_3   matlab.ui.control.CheckBox
        CheckBox_2   matlab.ui.control.CheckBox
        CheckBox     matlab.ui.control.CheckBox
        EditField    matlab.ui.control.EditField
        Label        matlab.ui.control.Label
        Button_6     matlab.ui.control.Button
        Button_5     matlab.ui.control.Button
        Button_4     matlab.ui.control.Button
        Button_3     matlab.ui.control.Button
        Button_2     matlab.ui.control.Button
        Button       matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            sv_pl=app.EditField.Value;
            sv_nm=app.EditField_2.Value;

            if app.CheckBox.Value==true
                 load('omega_m_c_a.mat');
                 sv_1=[sv_pl sv_nm '_f' '.mat'];
                 save(sv_1)
            end
            if app.CheckBox_2.Value==true
                %耦合动图
            end
            if app.CheckBox_3.Value==true
                 load('A_f_data.mat');
                 sv_3=[sv_pl sv_nm '_A_f' '.mat'];
                 save(sv_3)
            end
            if app.CheckBox_4.Value==true
                load('.\w_t.mat')
                sv_4=[sv_pl sv_nm '_w_t' '.mat'];
                save(sv_4)
            end
            if app.CheckBox_5.Value==true
                load('.\p_t.mat')
                sv_5=[sv_pl sv_nm '_w_t' '.mat'];
                save(sv_5)
            end
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            page4_1
            delete(app.UIFigure)
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            page3
            delete(app.UIFigure)
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            page4_0
            delete(app.UIFigure)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            page4
            delete(app.UIFigure)
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            page5_0
            delete(app.UIFigure)
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            page5_1
            delete(app.UIFigure)
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '不同模式下的振动';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '给定初始条件的振动';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '膜上某点的振动';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '参数设置';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.Text = '数据导出';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '腔内某点的振动';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '保存路径';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Text = '给定模式下，膜、腔、耦合体系的各阶频率';
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.Text = '给定初始条件，耦合体系振动动图';
            app.CheckBox_3 = uicheckbox(app.UIFigure);
            app.CheckBox_3.Text = '给定初始条件，耦合体系振动频谱数据';
            app.CheckBox_4 = uicheckbox(app.UIFigure);
            app.CheckBox_4.Text = '膜上某点振动，位移-时间数据';
            app.CheckBox_5 = uicheckbox(app.UIFigure);
            app.CheckBox_5.Text = '腔内某点振动，压强-时间数据';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '保存';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '结束';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '文件名称';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '频谱与声音';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = page6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end