classdef page3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        amLabel      matlab.ui.control.Label
        kgmLabel_2   matlab.ui.control.Label
        msLabel      matlab.ui.control.Label
        kgmLabel     matlab.ui.control.Label
        mLabel_2     matlab.ui.control.Label
        mLabel       matlab.ui.control.Label
        EditField_7  matlab.ui.control.NumericEditField
        Label_8      matlab.ui.control.Label
        EditField_6  matlab.ui.control.NumericEditField
        Label_7      matlab.ui.control.Label
        Button_3     matlab.ui.control.Button
        Image        matlab.ui.control.Image
        EditField_2  matlab.ui.control.NumericEditField
        Label_6      matlab.ui.control.Label
        EditField_5  matlab.ui.control.NumericEditField
        Label_5      matlab.ui.control.Label
        EditField_4  matlab.ui.control.NumericEditField
        Label_4      matlab.ui.control.Label
        EditField_3  matlab.ui.control.NumericEditField
        Label_3      matlab.ui.control.Label
        EditField_1  matlab.ui.control.NumericEditField
        Label_2      matlab.ui.control.Label
        Button       matlab.ui.control.Button
        Label        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)

            delete(app.UIFigure)
            page2
        end

        % Button pushed function: Button_3
        function Button_2Pushed(app, event)

            h=waitbar(0,'请稍等，计算耦合体系本征频率中');
            L=app.EditField_1.Value;
            a=app.EditField_2.Value;
            cs=app.EditField_3.Value;
            rho=app.EditField_4.Value;
            a0=app.EditField_5.Value;
            order_max=app.EditField_6.Value;
            sigma =app.EditField_7.Value;;% 气球面密度
            ca = 345;% 空气中声速
            cs_ca=cs/ca;L_a=L/a;sigma_a_rho=sigma/a/rho;
            load('rootBessel.mat')
            load('rootBesselDiff.mat')
            for n=0:10
                waitbar(n/10,h)
                OmegaResonance(n,10, cs_ca,L_a, sigma_a_rho,rootBessel,rootBesselDiff);
            end
            delete(h);
            save('para.mat','L_a','sigma_a_rho','cs_ca','a0','L','a','ca')

            fileList=dir('./OmegaRe/*.mat');
            %order_max=10;% 需要修改%%%%%%%%%%%%%
            Omega=zeros(length(fileList),order_max);
            for ni =1:length(fileList)
                load(strcat(fileList(ni).folder,'/', fileList(ni).name));
                while length(OmegaRe)<order_max
                    OmegaRe=[OmegaRe nan];
                end
                Omega(ni,:)=OmegaRe(1:order_max);
            end
            save('OmegaRe.mat',"Omega")

            fileList=dir('./Trans/*.mat');

            transMat=zeros(length(fileList),order_max,order_max);
            for ni =1:length(fileList)
                load(strcat(fileList(ni).folder,'/', fileList(ni).name));
                transMat(ni,:,:)=trans(1:order_max,1:order_max);
            end
            save('transMat.mat',"transMat")

            delete(app.UIFigure)

            load(".\OmegaRe.mat"); % 计算得到的OmegaRe
            load('.\transMat.mat'); % a^k_ni
            load('.\rootBessel.mat');
            load('.\rootBesselDiff.mat');
            load('.\para.mat')
            order_max=10;% 需要修改
            a0=0; %敲击位置，(theta=0)
            Omega=Omega(1:order_max+1,1:order_max)/2;% 一致化
            save('Omega.mat','Omega');
            %%
            r_num=100;
            theta_num=100;% 切分的数目
            z_num=100;
            r=linspace(0,1,r_num)';% 注意有转置，列矢*行矢比较方便 r(0)对应中间
            theta=linspace(0,2*pi,theta_num);
            z(1,1,:)=linspace(0,L,z_num);
            % z0=zeros(r_num,theta_num);
            % z0(floor(r_num*a0/a)+1,1)=1; % 敲击位置，方程可以后续再改
            save('cut_num.mat','r_num','theta_num','z_num');
            %%
            % 锥形初始条件
            h0=0.001;

            ab=sqrt(r.^2+a0^2-2*r*a0*cos(theta));
            cosoba=(r.^2+ab.^2-a0^2)/2./r./ab;
            coscbo=-cosoba;
            cb=(2*coscbo.*r+sqrt((2*coscbo.*r).^2-4*(r.^2-1^2)))/2;
            z0=h0*cb./(cb+ab);
            z0(isnan(z0))=h0*1/(1+a0);
            % surfShape(z0,r,theta);
            %%
            w_nm= zeros(size(Omega));% 膜在膜的本征态上的展开系数的矩阵
            shape_m=cell(order_max+1,order_max);% 记录膜在腔的本征态下的形状的cell
            % r1=r-a/2/r_num;r2=r+a/2/r_num;
            % ds=pi*(r2.^2-r1.^2)/theta_num;
            ds=pi*2*r/r_num/theta_num;% 和上面两行等价
            ds=repmat(ds,1,theta_num);
            w_cm=zeros(order_max+1,order_max);
            for nn=0:order_max
                for mm=1:order_max
                    if nn==0 && mm==1
                        z1=sqrt(2)*ones(size(r))*cos(nn*theta)/sqrt(pi)/sqrt(2);
                    elseif nn==0
                        z1=sqrt(2)*rootBesselDiff(nn+1,mm)*besselj(nn,rootBesselDiff(nn+1,mm)*r)/...
                            besselj(nn,rootBesselDiff(nn+1,mm))/sqrt(rootBesselDiff(nn+1,mm)^2-nn^2)*...
                            cos(nn*theta)/sqrt(pi)/sqrt(2);
                    else%mm 需要+1
                        z1=sqrt(2)*rootBesselDiff(nn+1,mm+1)*besselj(nn,rootBesselDiff(nn+1,mm+1)*r)/...
                            besselj(nn,rootBesselDiff(nn+1,mm+1))/sqrt(rootBesselDiff(nn+1,mm+1)^2-nn^2)*...
                            cos(nn*theta)/sqrt(pi);
                    end
                    % 先是r,后是theta
                    shape_m{nn+1,mm}=z1;
                    w_cm(nn+1,mm)=sum(sum(z0.*z1.*ds));
                end
            end
            shape=zeros(r_num,theta_num);
            for nn=0:order_max
                for mm=1:order_max
                    shape=shape+shape_m{nn+1,mm}*w_cm(nn+1,mm);
                end
            end
            %膜的初态按照腔的本征态展开
            %%
            % 角标应为n m k
            w_cn=zeros(size(w_cm));
            for nn=0:order_max
                %     w_cn(nn+1,:)=((reshape(transMat(nn+1,:,:),order_max,order_max))^-1*w_cm(nn+1,1:order_max)')';
                w_cn(nn+1,:)=w_cm(nn+1,1:order_max)*reshape(transMat(nn+1,1:order_max,1:order_max),order_max,order_max);
            end
            save('wcn.mat',"w_cn")
            page4
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '参数设置';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '上一步';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '鼓的高度';
            app.EditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_1.Value = 0.1;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '膜上波速';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.Value = 20;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '空气密度';
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.Value = 1.184;
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '小球下落位置';
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.Value = 0.1;
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '鼓的半径';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Value = 0.047;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'para_fig.png');
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_3.Text = '开始计算';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '计算阶数';
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6.Value = 10;
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '膜面密度';
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_7.Value = 20;
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Text = 'm';
            app.mLabel_2 = uilabel(app.UIFigure);
            app.mLabel_2.Text = 'm';
            app.kgmLabel = uilabel(app.UIFigure);
            app.kgmLabel.Text = 'kg/m';
            app.msLabel = uilabel(app.UIFigure);
            app.msLabel.Text = 'm/s';
            app.kgmLabel_2 = uilabel(app.UIFigure);
            app.kgmLabel_2.Text = 'kg/m³';
            app.amLabel = uilabel(app.UIFigure);
            app.amLabel.Text = '*a m';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = page3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end